classdef pong < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Pong                          matlab.ui.Figure
        MenuPanel                     matlab.ui.container.Panel
        PlayButton                    matlab.ui.control.Button
        Label_2                       matlab.ui.control.Label
        NickPlayer1EditField          matlab.ui.control.EditField
        Label                         matlab.ui.control.Label
        NickPlayer2EditField          matlab.ui.control.EditField
        HighscoreTOP10Label           matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        SkillButtonGroup              matlab.ui.container.ButtonGroup
        leichtButton                  matlab.ui.control.RadioButton
        mittelButton                  matlab.ui.control.RadioButton
        schwerButton                  matlab.ui.control.RadioButton
        ControlLabel                  matlab.ui.control.Label
        Player1ArrowUpArrowDownLabel  matlab.ui.control.Label
        Player2wsLabel                matlab.ui.control.Label
        RoundsEditFieldLabel          matlab.ui.control.Label
        RoundsEditField               matlab.ui.control.NumericEditField
        PONGLabel                     matlab.ui.control.Label
        GameModeButtonGroup           matlab.ui.container.ButtonGroup
        onePlayer                     matlab.ui.control.RadioButton
        twoPlayer                     matlab.ui.control.RadioButton
        RoundsSpinnerLabel            matlab.ui.control.Label
        RoundsSpinner                 matlab.ui.control.Spinner
    end

    
    properties (Access = public)
        DatabaseName=('pongHighscoreDatabase.csv')
       
      
    end

 
    methods (Access = private)


    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UITable.Data= pongHighscoreRead(app.DatabaseName);  %Liest die Highsocre Daten beim aufstarten der App ein und stellt diese auf dem UiTabel dar.
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            name1false = strcmp(app.NickPlayer1EditField.Value,'Nickname to long'); %überprüft ob der Nickname zu lang ist.
            name2false = strcmp(app.NickPlayer2EditField.Value,'Nickname to long');
           if ~(name1false|name2false)                                              %Spiel startet nur wen kein Nickname zu lang ist.


                app.Pong.Visible='off'  
                pongengine1(app);
            %app.MenuPanel.Visible='off'                              %Wechselt die Ansicht
            %app.PlayPanel.Visible='on'
            %app.PlayPanel.Position = [1 1 640 480];                  %Verschiebt die Ansicht.
        
            %run pongengine  %MatlabScript aufrufen
           end
           
        end

        % Selection changed function: GameModeButtonGroup
        function GameModeButtonGroupSelectionChanged(app, event)
            selectedButton = app.GameModeButtonGroup.SelectedObject.Text;
            switch selectedButton
                case '1 Player'
                    app.NickPlayer2EditField.Visible='Off';
                    app.SkillButtonGroup.Visible='On';
                case '2 Players'
                    app.NickPlayer2EditField.Visible='On';
                    app.SkillButtonGroup.Visible='Off';
                                    
            end
        end

        % Value changed function: NickPlayer1EditField
        function NickPlayer1EditFieldValueChanged(app, event)
            value = app.NickPlayer1EditField.Value;
            
            
            if length(value)>32%Gibt die Anzahl zeichen zurück
                app.NickPlayer1EditField.Value='Nickname to long' 
                
            end
        end

        % Value changed function: NickPlayer2EditField
        function NickPlayer2EditFieldValueChanged(app, event)
            value = app.NickPlayer2EditField.Value;
            if length(value)>32%Gibt die Anzahl zeichen zurück
                app.NickPlayer2EditField.Value='Nickname to long'
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Pong = uifigure;
            app.Pong.Name = 'Pong';
            app.MenuPanel = uipanel(app.Pong);
            app.MenuPanel.Title = 'Menu';
            app.PlayButton = uibutton(app.MenuPanel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play!';
            app.Label_2 = uilabel(app.MenuPanel);
            app.Label_2.Text = '';
            app.NickPlayer1EditField = uieditfield(app.MenuPanel, 'text');
            app.NickPlayer1EditField.ValueChangedFcn = createCallbackFcn(app, @NickPlayer1EditFieldValueChanged, true);
            app.NickPlayer1EditField.Value = 'Player 1 Name';
            app.Label = uilabel(app.MenuPanel);
            app.Label.Text = '';
            app.NickPlayer2EditField = uieditfield(app.MenuPanel, 'text');
            app.NickPlayer2EditField.ValueChangedFcn = createCallbackFcn(app, @NickPlayer2EditFieldValueChanged, true);
            app.NickPlayer2EditField.Visible = 'off';
            app.NickPlayer2EditField.Value = 'Player 2 Name';
            app.HighscoreTOP10Label = uilabel(app.MenuPanel);
            app.HighscoreTOP10Label.Text = 'Highscore TOP 10';
            app.UITable = uitable(app.MenuPanel);
            app.UITable.ColumnName = {'Rank'; 'Player'; 'Score'};
            app.UITable.RowName = {};
            app.SkillButtonGroup = uibuttongroup(app.MenuPanel);
            app.SkillButtonGroup.Title = 'Skill';
            app.leichtButton = uiradiobutton(app.SkillButtonGroup);
            app.leichtButton.Text = 'leicht';
            app.leichtButton.Value = true;
            app.mittelButton = uiradiobutton(app.SkillButtonGroup);
            app.mittelButton.Text = 'mittel';
            app.schwerButton = uiradiobutton(app.SkillButtonGroup);
            app.schwerButton.Text = 'schwer';
            app.ControlLabel = uilabel(app.MenuPanel);
            app.ControlLabel.Text = 'Control:';
            app.Player1ArrowUpArrowDownLabel = uilabel(app.MenuPanel);
            app.Player1ArrowUpArrowDownLabel.Text = 'Player1: Arrow Up / Arrow Down';
            app.Player2wsLabel = uilabel(app.MenuPanel);
            app.Player2wsLabel.Text = 'Player2: w / s';
            app.RoundsEditFieldLabel = uilabel(app.MenuPanel);
            app.RoundsEditFieldLabel.Text = 'Rounds';
            app.RoundsEditField = uieditfield(app.MenuPanel, 'numeric');
            app.RoundsEditField.Limits = [1 50];
            app.RoundsEditField.ValueDisplayFormat = '%.0f';
            app.RoundsEditField.Value = 7;
            app.PONGLabel = uilabel(app.MenuPanel);
            app.PONGLabel.Text = 'PONG';
            app.GameModeButtonGroup = uibuttongroup(app.MenuPanel);
            app.GameModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @GameModeButtonGroupSelectionChanged, true);
            app.GameModeButtonGroup.Title = 'Mode';
            app.onePlayer = uiradiobutton(app.GameModeButtonGroup);
            app.onePlayer.Text = '1 Player';
            app.onePlayer.Value = true;
            app.twoPlayer = uiradiobutton(app.GameModeButtonGroup);
            app.twoPlayer.Text = '2 Players';
            app.RoundsSpinnerLabel = uilabel(app.MenuPanel);
            app.RoundsSpinnerLabel.Text = 'Rounds';
            app.RoundsSpinner = uispinner(app.MenuPanel);
            app.RoundsSpinner.Limits = [0 50];
            app.RoundsSpinner.ValueDisplayFormat = '%.0f';
        end
    end

    methods (Access = public)

        % Construct app
        function app = pong

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Pong)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Pong)
        end
    end
end